classdef houghViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        hough_peaks_ax               matlab.ui.control.UIAxes
        rotSliderLabel               matlab.ui.control.Label
        rotSlider                    matlab.ui.control.Slider
        xtransSliderLabel            matlab.ui.control.Label
        xtransSlider                 matlab.ui.control.Slider
        ytransSliderLabel            matlab.ui.control.Label
        ytransSlider                 matlab.ui.control.Slider
        norm_hough_peaks_ax          matlab.ui.control.UIAxes
        histogram_ax                 matlab.ui.control.UIAxes
        img_axes                     matlab.ui.control.UIAxes
        SelectImageDropDownLabel     matlab.ui.control.Label
        SelectImageDropDown          matlab.ui.control.DropDown
        ResetTransformValuesButton   matlab.ui.control.Button
        FillValueEditFieldLabel      matlab.ui.control.Label
        FillValueEditField           matlab.ui.control.NumericEditField
        BoarderEditFieldLabel        matlab.ui.control.Label
        BoarderEditField             matlab.ui.control.NumericEditField
        xdimEditFieldLabel           matlab.ui.control.Label
        xdimEditField                matlab.ui.control.NumericEditField
        ydimEditFieldLabel           matlab.ui.control.Label
        ydimEditField                matlab.ui.control.NumericEditField
        ApplynewdimensionsButton     matlab.ui.control.Button
        ViewButtonGroup              matlab.ui.container.ButtonGroup
        RGBButton                    matlab.ui.control.RadioButton
        EdgeButton                   matlab.ui.control.RadioButton
        EdgeLineSegButton            matlab.ui.control.RadioButton
        MaxhoughpeaksEditFieldLabel  matlab.ui.control.Label
        MaxhoughpeaksEditField       matlab.ui.control.NumericEditField
        in_place_graph_ax            matlab.ui.control.UIAxes
        nBinsEditFieldLabel          matlab.ui.control.Label
        nBinsEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        imgs % Description
        img_show_orig % Description
        trans_vec = [0, 0]; % Description
        theta_trans % Description
        theta_tform = eye(3); % Description
        img_show % Description
        im_dims = [500, 500]; % Description
        fill_value = 255; % Description
        boarder_sz = 0; % Description
        edge_threshold_canny = 0.4; % Description
        dist_threshold = 100;
        max_nodes = 10; % Description
        normThetaRho % Description
        shiftedRho % Description
        grph % Description
        thetaRho % Description
        eigenValues % Description
        eigenHist % Description
        nBins = 20; % Description
    end
    
    methods (Access = private)
        
        function update_plots(app)
            % Apply Transformation
            a2dtform = affine2d(app.theta_tform);
            centerOutput = affineOutputView(size(app.img_show_orig), a2dtform, 'BoundsStyle', ...
                'CenterOutput');
            if any(app.trans_vec)
                app.img_show = imtranslate(imwarp(app.img_show_orig, a2dtform, 'cubic', ...
                    'OutputView', centerOutput, 'FillValues', app.fill_value), app.trans_vec, ...
                    'FillValues', app.fill_value);
            else
                app.img_show = imwarp(app.img_show_orig, a2dtform, 'cubic', 'OutputView', ...
                    centerOutput, 'FillValues', app.fill_value);
            end
            
            % Imshow
            selectedButton = app.ViewButtonGroup.SelectedObject;
            if selectedButton == app.RGBButton
                imshow(app.img_show, 'Parent', app.img_axes);
            elseif selectedButton == app.EdgeButton
                if length(size(app.img_show)) == 3
                    edge_img = edge(rgb2gray(app.img_show), 'Canny', app.edge_threshold_canny);
                else
                    edge_img = edge(app.img_show, 'Canny', app.edge_threshold_canny);
                end
                imshow(edge_img, 'Parent', app.img_axes);
            elseif selectedButton == app.EdgeLineSegButton
                % TODO: add Hough line segments
                if length(size(app.img_show)) == 3
                    edge_img = edge(rgb2gray(app.img_show), 'Canny', app.edge_threshold_canny);
                else
                    edge_img = edge(app.img_show, 'Canny', app.edge_threshold_canny);
                end
                imshow(edge_img, 'Parent', app.img_axes);
            end
            
            % Make graph and return conventional and normalized Hough values
            if length(size(app.img_show)) == 3
                [app.grph, app.normThetaRho, app.thetaRho] = image2Graph(rgb2gray(app.img_show), ...
                    app.max_nodes, app.dist_threshold);
            else
                [app.grph, app.normThetaRho, app.thetaRho] = image2Graph(app.img_show, ...
                    app.max_nodes, app.dist_threshold);
            end
            
            % Hough Peaks Plot
            scatter(app.thetaRho(:, 1), app.thetaRho(:, 2)', 'Parent', app.hough_peaks_ax);
            
            % Normalized Hough Peaks Plot
            scatter(app.normThetaRho(:, 1)', app.normThetaRho(:, 2)', 'Parent', app.norm_hough_peaks_ax);
            
            % In-place graph plot
            plot(app.grph, 'XData', app.normThetaRho(:, 1), 'YData', app.normThetaRho(:, 2), 'Parent', app.in_place_graph_ax)
            
            % Calculate eigenvalues of normalized laplacian
            app.eigenValues = getEigenVals(app.grph);
            
            % Calculate the histogram vector (not used for plotting)
            app.eigenHist = makeHistVecOn0to2(app.eigenValues, app.nBins);
            
            % Plot histogram of eigenvalues
            histogram(app.histogram_ax, app.eigenHist, app.nBins);
        end
        
        function load_in_imgs(app)
            addpath(fullfile(cd, "src"));
            [app.imgs, lbls] = load_imgs_std_sz_lbls(fullfile(cd, "data"), "*.jpg", app.im_dims);
            app.SelectImageDropDown.Items = lbls;
            values = cell(length(lbls), 1);
            counter = 1;
            for i=1:length(lbls)
                values{i} = counter;
                counter = counter + 1;
            end
            app.SelectImageDropDown.ItemsData = values;
            value = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(value, :, :, :));
        end
        
        function apply_rot_to_tform(app)
            cos_value = cos(app.theta_trans);
            sin_value = sin(app.theta_trans);
            app.theta_tform(1, 1) = cos_value;
            app.theta_tform(1, 2) = -sin_value;
            app.theta_tform(2, 1) = sin_value;
            app.theta_tform(2, 2) = cos_value;
        end
        
        function update_slider_ranges(app)
            app.xtransSlider.Limits = [-app.im_dims(2) - app.boarder_sz, app.im_dims(2) + app.boarder_sz];
            app.ytransSlider.Limits = [-app.im_dims(1) - app.boarder_sz, app.im_dims(1) + app.boarder_sz];
        end
        
        function change_boarder(app)
            selected = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(selected, :, :, :));
            if length(size(app.img_show_orig)) == 3
                new_sz = [2*app.boarder_sz + app.im_dims(1), 2*app.boarder_sz + app.im_dims(2), 3];
                tmp = ones(new_sz, 'uint8').*app.fill_value;
                tmp(app.boarder_sz + 1:app.boarder_sz + app.im_dims(1), app.boarder_sz + 1:app.boarder_sz + app.im_dims(2), :) = app.img_show_orig;
            else
                new_sz = [2*app.boarder_sz + app.im_dims(1), 2*app.boarder_sz + app.im_dims(2)];
                tmp = ones(new_sz, 'uint8').*app.fill_value;
                tmp(app.boarder_sz + 1:app.boarder_sz + app.im_dims(1), app.boarder_sz + 1:app.boarder_sz + app.im_dims(2)) = app.img_show_orig;
            end
            
            app.img_show_orig = tmp;
            app.update_slider_ranges();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.load_in_imgs();
            app.nBinsEditField.Value = app.nBins;  % use the square root choice convention
            app.update_slider_ranges();
            app.FillValueEditField.Value = app.fill_value;
            app.MaxhoughpeaksEditField.Value = app.max_nodes;
            app.xdimEditField.Value = app.im_dims(2);
            app.ydimEditField.Value = app.im_dims(1);
            app.update_plots();
        end

        % Value changed function: rotSlider
        function rotSliderValueChanged(app, event)
            app.theta_trans = (event.Value * pi) / 180;
            app.apply_rot_to_tform();
            app.update_plots();
        end

        % Value changed function: SelectImageDropDown
        function SelectImageDropDownValueChanged(app, event)
            value = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(value, :, :, :));
            app.change_boarder();
            app.update_plots();
        end

        % Value changed function: xtransSlider
        function xtransSliderValueChanged(app, event)
            app.trans_vec(1) = app.xtransSlider.Value;
            app.update_plots();
        end

        % Value changed function: ytransSlider
        function ytransSliderValueChanged(app, event)
            app.trans_vec(2) = app.ytransSlider.Value;
            app.update_plots();
        end

        % Value changing function: rotSlider
        function rotSliderValueChanging(app, event)
            app.theta_trans = (event.Value * pi) / 180;
            app.apply_rot_to_tform();
            app.update_plots;
        end

        % Value changing function: xtransSlider
        function xtransSliderValueChanging(app, event)
            app.trans_vec(1) = event.Value;
            app.update_plots();
        end

        % Value changing function: ytransSlider
        function ytransSliderValueChanging(app, event)
            app.trans_vec(2) = event.Value;
            app.update_plots();
        end

        % Value changed function: FillValueEditField
        function FillValueEditFieldValueChanged(app, event)
            app.fill_value = app.FillValueEditField.Value;
            app.update_plots();
        end

        % Button pushed function: ResetTransformValuesButton
        function ResetTransformValuesButtonPushed(app, event)
            app.theta_trans = 0;
            app.apply_rot_to_tform();
            app.trans_vec = [0, 0];
            app.rotSlider.Value = 0;
            app.xtransSlider.Value = 0;
            app.ytransSlider.Value = 0;
            app.update_plots();
        end

        % Value changed function: BoarderEditField
        function BoarderEditFieldValueChanged(app, event)
            app.boarder_sz = app.BoarderEditField.Value;
            app.change_boarder();
            app.update_plots();
        end

        % Button pushed function: ApplynewdimensionsButton
        function ApplynewdimensionsButtonPushed(app, event)
            app.im_dims = [app.ydimEditField.Value, app.xdimEditField.Value];
            app.load_in_imgs();
            app.change_boarder();
            app.update_plots();
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            app.update_plots();
        end

        % Value changed function: MaxhoughpeaksEditField
        function MaxhoughpeaksEditFieldValueChanged(app, event)
            app.max_nodes = app.MaxhoughpeaksEditField.Value;
            app.update_plots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.hough_peaks_ax = uiaxes(app.UIFigure);
            title(app.hough_peaks_ax, 'Hough Peaks')
            xlabel(app.hough_peaks_ax, '\theta')
            ylabel(app.hough_peaks_ax, '\rho')
            app.hough_peaks_ax.XLim = [-90 90];
            app.hough_peaks_ax.YLim = [-300 300];
            app.hough_peaks_ax.ZLim = [-200 200];
            app.rotSliderLabel = uilabel(app.UIFigure);
            app.rotSliderLabel.Text = 'rot';
            app.rotSlider = uislider(app.UIFigure);
            app.rotSlider.Limits = [-180 180];
            app.rotSlider.ValueChangedFcn = createCallbackFcn(app, @rotSliderValueChanged, true);
            app.rotSlider.ValueChangingFcn = createCallbackFcn(app, @rotSliderValueChanging, true);
            app.xtransSliderLabel = uilabel(app.UIFigure);
            app.xtransSliderLabel.Text = 'x-trans';
            app.xtransSlider = uislider(app.UIFigure);
            app.xtransSlider.Limits = [-1 1];
            app.xtransSlider.ValueChangedFcn = createCallbackFcn(app, @xtransSliderValueChanged, true);
            app.xtransSlider.ValueChangingFcn = createCallbackFcn(app, @xtransSliderValueChanging, true);
            app.ytransSliderLabel = uilabel(app.UIFigure);
            app.ytransSliderLabel.Text = 'y-trans';
            app.ytransSlider = uislider(app.UIFigure);
            app.ytransSlider.ValueChangedFcn = createCallbackFcn(app, @ytransSliderValueChanged, true);
            app.ytransSlider.ValueChangingFcn = createCallbackFcn(app, @ytransSliderValueChanging, true);
            app.norm_hough_peaks_ax = uiaxes(app.UIFigure);
            title(app.norm_hough_peaks_ax, 'Normalized Hough Peaks')
            xlabel(app.norm_hough_peaks_ax, '\theta')
            ylabel(app.norm_hough_peaks_ax, '\rho')
            app.histogram_ax = uiaxes(app.UIFigure);
            title(app.histogram_ax, 'Eigenvalues of Laplacian')
            xlabel(app.histogram_ax, 'value')
            ylabel(app.histogram_ax, 'count')
            app.img_axes = uiaxes(app.UIFigure);
            title(app.img_axes, 'Image')
            xlabel(app.img_axes, '')
            ylabel(app.img_axes, '')
            app.img_axes.XTick = [];
            app.img_axes.YTick = [];
            app.SelectImageDropDownLabel = uilabel(app.UIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
            app.SelectImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropDownValueChanged, true);
            app.ResetTransformValuesButton = uibutton(app.UIFigure, 'push');
            app.ResetTransformValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransformValuesButtonPushed, true);
            app.ResetTransformValuesButton.Text = 'Reset Transform Values';
            app.FillValueEditFieldLabel = uilabel(app.UIFigure);
            app.FillValueEditFieldLabel.Text = 'Fill Value';
            app.FillValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FillValueEditField.Limits = [0 255];
            app.FillValueEditField.ValueDisplayFormat = '%.0f';
            app.FillValueEditField.ValueChangedFcn = createCallbackFcn(app, @FillValueEditFieldValueChanged, true);
            app.FillValueEditField.Value = 255;
            app.BoarderEditFieldLabel = uilabel(app.UIFigure);
            app.BoarderEditFieldLabel.Text = 'Boarder';
            app.BoarderEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoarderEditField.Limits = [0 1000];
            app.BoarderEditField.RoundFractionalValues = 'on';
            app.BoarderEditField.ValueDisplayFormat = '%.0f';
            app.BoarderEditField.ValueChangedFcn = createCallbackFcn(app, @BoarderEditFieldValueChanged, true);
            app.xdimEditFieldLabel = uilabel(app.UIFigure);
            app.xdimEditFieldLabel.Text = 'x dim';
            app.xdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.xdimEditField.Limits = [0 2000];
            app.xdimEditField.ValueDisplayFormat = '%.0f';
            app.ydimEditFieldLabel = uilabel(app.UIFigure);
            app.ydimEditFieldLabel.Text = 'y dim';
            app.ydimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ydimEditField.Limits = [0 2000];
            app.ydimEditField.ValueDisplayFormat = '%.0f';
            app.ApplynewdimensionsButton = uibutton(app.UIFigure, 'push');
            app.ApplynewdimensionsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynewdimensionsButtonPushed, true);
            app.ApplynewdimensionsButton.Text = 'Apply new dimensions';
            app.ViewButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.RGBButton = uiradiobutton(app.ViewButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.EdgeButton = uiradiobutton(app.ViewButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.EdgeLineSegButton = uiradiobutton(app.ViewButtonGroup);
            app.EdgeLineSegButton.Text = 'Edge + Line Seg.';
            app.MaxhoughpeaksEditFieldLabel = uilabel(app.UIFigure);
            app.MaxhoughpeaksEditFieldLabel.Text = 'Max hough peaks';
            app.MaxhoughpeaksEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxhoughpeaksEditField.Limits = [1 300];
            app.MaxhoughpeaksEditField.ValueDisplayFormat = '%.0f';
            app.MaxhoughpeaksEditField.ValueChangedFcn = createCallbackFcn(app, @MaxhoughpeaksEditFieldValueChanged, true);
            app.MaxhoughpeaksEditField.Value = 100;
            app.in_place_graph_ax = uiaxes(app.UIFigure);
            title(app.in_place_graph_ax, 'In Place Graph')
            xlabel(app.in_place_graph_ax, '')
            ylabel(app.in_place_graph_ax, '')
            app.nBinsEditFieldLabel = uilabel(app.UIFigure);
            app.nBinsEditFieldLabel.Text = 'nBins';
            app.nBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nBinsEditField.Limits = [0 200];
            app.nBinsEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = houghViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end