classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DropDownLabel        matlab.ui.control.Label
        SelectImageDropDown  matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        function load_in_imgs(app)
            [app.imgs, lbls] = load_imgs_std_sz_lbls("data", "*.jpg", app.im_dims);
            app.SelectImageDropDown.Items = lbls;
            values = cell(length(lbls), 1);
            counter = 1;
            for i=1:length(lbls)
                values{i} = counter;
                counter = counter + 1;
            end
            app.SelectImageDropDown.ItemsData = values;
            value = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(value, :, :, :));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fullfile(cd, "src"));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end